classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DesiredYpointEditField       matlab.ui.control.NumericEditField
        DesiredYpointEditFieldLabel  matlab.ui.control.Label
        DesiredXpointEditField       matlab.ui.control.NumericEditField
        DesiredXpointEditFieldLabel  matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Read input values
            desired_x = app.DesiredXpointEditField.Value;
            desired_y = app.DesiredYpointEditField.Value;

            % Define parameters
            L1_length = 4;
            L2_length = 3;
            x_base = 0;
            y_base = 0;

            % Define symbolic variables
            syms theta_1 theta_2

            % Define the kinematics equations
            x_l1 = x_base + L1_length * cosd(theta_1);
            y_l1 = y_base + L1_length * sind(theta_1);
            x_l2 = x_l1 + L2_length * cosd(theta_2); % x_l2 == x_end_effector
            y_l2 = y_l1 + L2_length * sind(theta_2); % y_l2 == y_end_effector

            % Solve for theta_1 and theta_2
            [theta_1_sol, theta_2_sol] = solve([x_l2 == desired_x, y_l2 == desired_y], [theta_1, theta_2]);

            % Choose a specific solution (if there are multiple solutions)
            theta_1_num = double(theta_1_sol(1));
            theta_2_num = double(theta_2_sol(1));

            % Calculate numeric positions of the links
            x_l1_num = double(x_base + L1_length * cosd(theta_1_num));
            y_l1_num = double(y_base + L1_length * sind(theta_1_num));
            x_l2_num = double(x_l1_num + L2_length * cosd(theta_2_num));
            y_l2_num = double(y_l1_num + L2_length * sind(theta_2_num));

            % Plot the robot arm
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, [-1 8 -1 8]);
            plot(app.UIAxes, [x_base, x_l1_num], [y_base, y_l1_num], 'o-', 'LineWidth', 4);
            plot(app.UIAxes, [x_l1_num, x_l2_num], [y_l1_num, y_l2_num], 'o-', 'LineWidth', 4);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, {'L1', 'L2'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inverse Kinematic 2 DOF ')
            xlabel(app.UIAxes, 'X position')
            ylabel(app.UIAxes, 'Y position')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'};
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DesiredXpointEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredXpointEditFieldLabel.Text = 'Desired X point';
            app.DesiredXpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredYpointEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredYpointEditFieldLabel.Text = 'Desired Y point';
            app.DesiredYpointEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end